classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditField               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        DEMOButton              matlab.ui.control.Button
        TerminarButton          matlab.ui.control.Button
        VelocidadSlider         matlab.ui.control.Slider
        VelocidadSliderLabel    matlab.ui.control.Label
        ANGULOSButton           matlab.ui.control.Button
        PinzaButton             matlab.ui.control.Button
        AnguloEditField_4       matlab.ui.control.NumericEditField
        AnguloEditField_4Label  matlab.ui.control.Label
        Q4zButton_2             matlab.ui.control.Button
        Q4zButton               matlab.ui.control.Button
        AnguloEditField_2       matlab.ui.control.NumericEditField
        AnguloEditField_2Label  matlab.ui.control.Label
        Q4xButton_2             matlab.ui.control.Button
        Q4xButton               matlab.ui.control.Button
        AnguloEditField_3       matlab.ui.control.NumericEditField
        AnguloEditField_3Label  matlab.ui.control.Label
        Q3Button_2              matlab.ui.control.Button
        Q3Button                matlab.ui.control.Button
        AnguloEditField_5       matlab.ui.control.NumericEditField
        AnguloEditField_5Label  matlab.ui.control.Label
        Q2Button_2              matlab.ui.control.Button
        Q2Button                matlab.ui.control.Button
        AnguloEditField         matlab.ui.control.NumericEditField
        AnguloEditFieldLabel    matlab.ui.control.Label
        Q1Button_2              matlab.ui.control.Button
        Q1Button                matlab.ui.control.Button
    end

    
    properties (Access = private)
        s   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnguloEditField.Value = 180; 
            app.AnguloEditField_5.Value = 90; 
            app.AnguloEditField_3.Value = 90; 
            app.AnguloEditField_2.Value = 0; 
            app.AnguloEditField_4.Value = 0; 
            app.EditField.Value = 0;    % Establecer el valor inicial
            app.s = serialport("COM10", 9600);
            fopen(app.s);

        end

        % Button pushed function: Q1Button
        function Q1ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            % Obtener el valor actual del EditField
            valor_actual = app.AnguloEditField.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual + 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor <=360 
                app.AnguloEditField.Value = nuevo_valor;
            else
                 app.AnguloEditField.Value = valor_actual;
            end
            %app.AnguloEditField.Value = nuevo_valor;
            %msg = ['0', vel_str, '005'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q1Button_2
        function Q1Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual - 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor >=0 
                app.AnguloEditField.Value = nuevo_valor;
            else
                 app.AnguloEditField.Value = valor_actual;
            end
            %msg = ['0', vel_str, '105'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button
        function Q2ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_5.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual + 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor <=180 
                app.AnguloEditField_5.Value = nuevo_valor;
            else
                 app.AnguloEditField_5.Value = valor_actual;
            end
            %msg = ['0', vel_str, '015'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button_2
        function Q2Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_5.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual - 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor >=0 
                app.AnguloEditField_5.Value = nuevo_valor;
            else
                 app.AnguloEditField_5.Value = valor_actual;
            end
            %msg = ['0', vel_str, '115'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button
        function Q3ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_3.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual + 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor <=180 
                app.AnguloEditField_3.Value = nuevo_valor;
            else
                 app.AnguloEditField_3.Value = valor_actual;
            end
            %msg = ['0', vel_str, '025'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button_2
        function Q3Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_3.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual - 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor >=0 
                app.AnguloEditField_3.Value = nuevo_valor;
            else
                 app.AnguloEditField_3.Value = valor_actual;
            end
            %msg = ['0', vel_str, '125'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4xButton
        function Q4xButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_2.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual + 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor <=180 
                app.AnguloEditField_2.Value = nuevo_valor;
            else
                 app.AnguloEditField_2.Value = valor_actual;
            end
            %msg = ['0', vel_str, '035'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4xButton_2
        function Q4xButton_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_2.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual - 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor >=0 
                app.AnguloEditField_2.Value = nuevo_valor;
            else
                 app.AnguloEditField_2.Value = valor_actual;
            end
            %msg = ['0', vel_str, '135'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4zButton
        function Q4zButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_4.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual + 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor <=360 
                app.AnguloEditField_4.Value = nuevo_valor;
            else
                 app.AnguloEditField_4.Value = valor_actual;
            end
            %msg = ['0', vel_str, '045'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4zButton_2
        function Q4zButton_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            valor_actual = app.AnguloEditField_4.Value;
            % Sumar 5 al valor actual
            nuevo_valor = valor_actual - 5;
            % Actualizar el valor del EditField con el nuevo resultado
            if nuevo_valor >=0 
                app.AnguloEditField_4.Value = nuevo_valor;
            else
                 app.AnguloEditField_4.Value = valor_actual;
            end
            %msg = ['0', vel_str, '145'];
            %fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: TerminarButton
        function TerminarButtonPushed(app, event)
            fclose(app.s);  % Cerrar el puerto serial
            delete(app.s);
            delete(app.UIFigure);
        end

        % Value changed function: AnguloEditField
        function AnguloEditFieldValueChanged(app, event)


        end

        % Button pushed function: DEMOButton
        function DEMOButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            ang1=app.AnguloEditField.Value ;   %No importa qué hay, solo importa 1 al inicio
            ang1= num2str(round(ang1), '%03d');
            ang2=app.AnguloEditField_5.Value ;
            ang2= num2str(round(ang1), '%03d');
            ang3=app.AnguloEditField_3.Value ;
            ang3= num2str(round(ang1), '%03d');
            ang4=app.AnguloEditField_2.Value ;
            ang4= num2str(round(ang1), '%03d');
            ang5=app.AnguloEditField_4.Value ;
            ang5= num2str(round(ang1), '%03d');
            pinzaon= app.EditField.Value;
            msg = ['1', vel_str,ang1,ang2,ang3,ang4,ang5, pinzaon];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: PinzaButton
        function PinzaButtonPushed(app, event)
            valor_actual = app.EditField.Value;
            if valor_actual == 1
                valor_actual = 0;
            else
                valor_actual = 1;
            end
            app.EditField.Value = valor_actual;
        end

        % Button pushed function: ANGULOSButton
        function ANGULOSButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            ang1=app.AnguloEditField.Value ;
            ang1= num2str(round(ang1), '%03d');
            ang2=app.AnguloEditField_5.Value ;
            ang2= num2str(round(ang2), '%03d');
            ang3=app.AnguloEditField_3.Value ;
            ang3= num2str(round(ang3), '%03d');
            ang4x=app.AnguloEditField_2.Value ;
            ang4x= num2str(round(ang4x), '%03d');
            ang4z=app.AnguloEditField_4.Value ;
            ang4z= num2str(round(ang4z), '%03d');
            pinzaon= app.EditField.Value; 
            pinzaon = num2str(pinzaon, '%01d'); 
            msg = ['0',vel_str,ang1,ang2,ang3,ang4x,ang4z,pinzaon];
            fprintf(app.s, '%s', msg); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q1Button = uibutton(app.UIFigure, 'push');
            app.Q1Button.ButtonPushedFcn = createCallbackFcn(app, @Q1ButtonPushed, true);
            app.Q1Button.Text = 'Q1 +';
            app.Q1Button_2 = uibutton(app.UIFigure, 'push');
            app.Q1Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q1Button_2Pushed, true);
            app.Q1Button_2.Text = 'Q1 -';
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.AnguloEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloEditField.ValueChangedFcn = createCallbackFcn(app, @AnguloEditFieldValueChanged, true);
            app.Q2Button = uibutton(app.UIFigure, 'push');
            app.Q2Button.ButtonPushedFcn = createCallbackFcn(app, @Q2ButtonPushed, true);
            app.Q2Button.Text = 'Q2 +';
            app.Q2Button_2 = uibutton(app.UIFigure, 'push');
            app.Q2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q2Button_2Pushed, true);
            app.Q2Button_2.Text = 'Q2 -';
            app.AnguloEditField_5Label = uilabel(app.UIFigure);
            app.AnguloEditField_5Label.Text = 'Angulo';
            app.AnguloEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Q3Button = uibutton(app.UIFigure, 'push');
            app.Q3Button.ButtonPushedFcn = createCallbackFcn(app, @Q3ButtonPushed, true);
            app.Q3Button.Text = 'Q3 +';
            app.Q3Button_2 = uibutton(app.UIFigure, 'push');
            app.Q3Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q3Button_2Pushed, true);
            app.Q3Button_2.Text = 'Q3 -';
            app.AnguloEditField_3Label = uilabel(app.UIFigure);
            app.AnguloEditField_3Label.Text = 'Angulo';
            app.AnguloEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Q4xButton = uibutton(app.UIFigure, 'push');
            app.Q4xButton.ButtonPushedFcn = createCallbackFcn(app, @Q4xButtonPushed, true);
            app.Q4xButton.Text = 'Q4x +';
            app.Q4xButton_2 = uibutton(app.UIFigure, 'push');
            app.Q4xButton_2.ButtonPushedFcn = createCallbackFcn(app, @Q4xButton_2Pushed, true);
            app.Q4xButton_2.Text = 'Q4x -';
            app.AnguloEditField_2Label = uilabel(app.UIFigure);
            app.AnguloEditField_2Label.Text = 'Angulo';
            app.AnguloEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q4zButton = uibutton(app.UIFigure, 'push');
            app.Q4zButton.ButtonPushedFcn = createCallbackFcn(app, @Q4zButtonPushed, true);
            app.Q4zButton.Text = 'Q4z +';
            app.Q4zButton_2 = uibutton(app.UIFigure, 'push');
            app.Q4zButton_2.ButtonPushedFcn = createCallbackFcn(app, @Q4zButton_2Pushed, true);
            app.Q4zButton_2.Text = 'Q4z -';
            app.AnguloEditField_4Label = uilabel(app.UIFigure);
            app.AnguloEditField_4Label.Text = 'Angulo';
            app.AnguloEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PinzaButton = uibutton(app.UIFigure, 'push');
            app.PinzaButton.ButtonPushedFcn = createCallbackFcn(app, @PinzaButtonPushed, true);
            app.PinzaButton.Text = 'Pinza';
            app.ANGULOSButton = uibutton(app.UIFigure, 'push');
            app.ANGULOSButton.ButtonPushedFcn = createCallbackFcn(app, @ANGULOSButtonPushed, true);
            app.ANGULOSButton.Text = 'ANGULOS';
            app.VelocidadSliderLabel = uilabel(app.UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.UIFigure);
            app.TerminarButton = uibutton(app.UIFigure, 'push');
            app.TerminarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarButtonPushed, true);
            app.TerminarButton.Text = 'Terminar';
            app.DEMOButton = uibutton(app.UIFigure, 'push');
            app.DEMOButton.ButtonPushedFcn = createCallbackFcn(app, @DEMOButtonPushed, true);
            app.DEMOButton.Text = 'DEMO';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end